classdef microarrayDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        MicroarraylayoutAddspotswithShiftclickremovewithCtrlclickLabel  matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        bSaveLayout                  matlab.ui.control.Button
        bLoadLayout                  matlab.ui.control.Button
        sSpotSize                    matlab.ui.control.Spinner
        SpotsizemSpinnerLabel        matlab.ui.control.Label
        bLoadTraces                  matlab.ui.control.Button
        axMicroarray                 matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        OutputPanel                  matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        bDemuxTraces                 matlab.ui.control.Button
        chkCreateSubdir              matlab.ui.control.CheckBox
        EdgedetectionPanel           matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        bLoadSettings                matlab.ui.control.Button
        bSaveSettings                matlab.ui.control.Button
        GridLayout6_2                matlab.ui.container.GridLayout
        sSigma                       matlab.ui.control.Spinner
        SigmaSpinnerLabel            matlab.ui.control.Label
        sHighThreshold               matlab.ui.control.Spinner
        HighthresholdLabel           matlab.ui.control.Label
        sLowThreshold                matlab.ui.control.Spinner
        LowthresholdSpinnerLabel     matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        ddDownscale2                 matlab.ui.control.DropDown
        Downscalestep2DropDownLabel  matlab.ui.control.Label
        sDilation                    matlab.ui.control.Spinner
        DilationpxSpinnerLabel       matlab.ui.control.Label
        ddDownscale1                 matlab.ui.control.DropDown
        Downscalestep1DropDownLabel  matlab.ui.control.Label
        axCannyOut                   matlab.ui.control.UIAxes
        axCannyIn                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        traces_xy = struct('X', [], 'Y', [], 'nX', [], 'nY', []);      % Coordinates of loaded traces
        px_size = 6.5*2/60; % Pixel size in µm
        traces_files = [];  % Open traces files
        Circles = struct('patch', {}, 'position', {}, 'text', {}, 'id', {}); % Circle data
        AvailableIDs = []; % Pool of reusable IDs
        NextID = 1; % ID for the next circle
        SpotSize = 50; % Size of the circles
    end
    
    methods (Access = private)
        
        function updateAxMicroarray(app)
            tXY = app.traces_xy;
            px = app.px_size;
            ax = app.axMicroarray;

            cla(ax);
            hold(ax, 'on');

            if numel(app.traces_xy.X) > 0
                set(ax, 'Color', 'k');
                rectangle(ax, 'Position', [0, 0, tXY.nX*px, tXY.nY*px], 'FaceColor', 'w', 'EdgeColor', 'none');
                scatter(ax, tXY.X*px, tXY.Y*px, 3, 'k', 'filled');
            else
                set(ax, 'Color', 'w');
            end

            axis(ax, 'equal');
            box(ax, 'on');
            xlabel(ax, 'x, µm');
            ylabel(ax, 'y, µm');
        end
        
        function addCircle(app, x, y)
            'Adding a circle '
            spotFillColor   = [0.6 0.7 1.0];
            spotEdgeColor   = [0 0 0.8];
            spotAlpha       = 0.4;

            ss = app.sSpotSize.Value;
            % Determine the ID for the new circle
            if ~isempty(app.AvailableIDs)
                id = app.AvailableIDs(1); % Reuse the first available ID
                app.AvailableIDs(1) = []; % Remove it from the pool
            else
                id = app.NextID; % Use the next sequential ID
                app.NextID = app.NextID + 1; % Increment for future use
            end
    
            % Create the circle patch
            theta = linspace(0, 2*pi, 100);
            xCircle = x + ss * cos(theta);
            yCircle = y + ss * sin(theta);
            patchHandle = patch('XData', xCircle, 'YData', yCircle, ...
                                'FaceColor', spotFillColor, 'EdgeColor', spotEdgeColor, ...
                                'FaceAlpha', spotAlpha, ...
                                'Parent', app.axMicroarray);
    
            % Add text label for the circle
            textHandle = text(app.axMicroarray, x, y, num2str(id), ...
                              'HorizontalAlignment', 'center', ...
                              'VerticalAlignment', 'middle', ...
                              'Color', 'k', 'FontSize', 25, ...
                              'FontWeight', 'bold');
    
            % Store circle data
            app.Circles(end+1).patch = patchHandle;
            app.Circles(end).position = [x, y];
            app.Circles(end).text = textHandle;
            app.Circles(end).id = id;
        end

        function deleteCircle(app, x, y)
            'Deleting a circle'

            ss = app.sSpotSize.Value;
            for i = length(app.Circles):-1:1
                pos = app.Circles(i).position;
                dist = sqrt((x - pos(1))^2 + (y - pos(2))^2);
                if dist < ss
                    % Delete patch and text
                    delete(app.Circles(i).patch);
                    delete(app.Circles(i).text);
    
                    % Add ID to reusable pool
                    app.AvailableIDs(end+1) = app.Circles(i).id;
                    app.AvailableIDs = sort(app.AvailableIDs);
    
                    % Remove circle from list
                    app.Circles(i) = [];
                    return;
                end
            end
        end

        function dragCircle(app, x, y)
            'Dragging a circle'

            ss = app.sSpotSize.Value;
            for i = length(app.Circles):-1:1
                pos = app.Circles(i).position;
                dist = sqrt((x - pos(1))^2 + (y - pos(2))^2);
                if dist < ss
                    % Update position
                    app.Circles(i).position = [x, y];
    
                    % Update patch coordinates
                    theta = linspace(0, 2*pi, 100);
                    xCircle = x + ss * cos(theta);
                    yCircle = y + ss * sin(theta);
                    set(app.Circles(i).patch, 'XData', xCircle, 'YData', yCircle);
    
                    % Update text position
                    set(app.Circles(i).text, 'Position', [x, y]);
                    return;
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bLoadTraces
        function bLoadTracesButtonPushed(app, event)
            filter = {'*.rawtraces','Raw Traces Files (*.rawtraces)'; ...
              '*.traces','Binary Traces Files (*.traces)';};
            app.traces_files = getFiles(filter);
            app.traces_xy = loadTracesXY(app.traces_files);
            app.updateAxMicroarray();
        end

        % Button down function: axMicroarray
        function axMicroarrayButtonDown(app, event)
            % Get current point
            pt = get(app.axMicroarray, 'CurrentPoint');
            x = pt(1, 1);
            y = pt(1, 2);
    
            % Check for modifier keys
            modifiers = get(app.UIFigure, 'CurrentModifier');
            isShift = ismember('shift', modifiers);
            isCtrl = ismember('control', modifiers);
    
            if isShift
                app.addCircle(x, y);
            elseif isCtrl
                app.deleteCircle(x, y);
            else
                app.dragCircle(x, y);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {515, 515};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {422, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.axMicroarray = uiaxes(app.GridLayout2);
            xlabel(app.axMicroarray, 'X')
            ylabel(app.axMicroarray, 'Y')
            zlabel(app.axMicroarray, 'Z')
            app.axMicroarray.ButtonDownFcn = createCallbackFcn(app, @axMicroarrayButtonDown, true);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.bLoadTraces = uibutton(app.GridLayout3, 'push');
            app.bLoadTraces.ButtonPushedFcn = createCallbackFcn(app, @bLoadTracesButtonPushed, true);
            app.bLoadTraces.Text = 'Load traces';
            app.SpotsizemSpinnerLabel = uilabel(app.GridLayout3);
            app.SpotsizemSpinnerLabel.Text = 'Spot size, µm';
            app.sSpotSize = uispinner(app.GridLayout3);
            app.sSpotSize.Step = 5;
            app.sSpotSize.Limits = [10 250];
            app.sSpotSize.RoundFractionalValues = 'on';
            app.sSpotSize.Value = 100;
            app.bLoadLayout = uibutton(app.GridLayout3, 'push');
            app.bLoadLayout.Text = 'Load layout';
            app.bSaveLayout = uibutton(app.GridLayout3, 'push');
            app.bSaveLayout.Text = 'Save layout';
            app.MicroarraylayoutAddspotswithShiftclickremovewithCtrlclickLabel = uilabel(app.GridLayout2);
            app.MicroarraylayoutAddspotswithShiftclickremovewithCtrlclickLabel.Text = {'Microarray layout'; 'Add spots with Shift+click, remove with Ctrl+click'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.EdgedetectionPanel = uipanel(app.GridLayout4);
            app.EdgedetectionPanel.Title = 'Edge detection';
            app.GridLayout5 = uigridlayout(app.EdgedetectionPanel);
            app.axCannyIn = uiaxes(app.GridLayout5);
            title(app.axCannyIn, 'Canny edges input')
            xlabel(app.axCannyIn, 'X')
            ylabel(app.axCannyIn, 'Y')
            zlabel(app.axCannyIn, 'Z')
            app.axCannyOut = uiaxes(app.GridLayout5);
            title(app.axCannyOut, 'Canny edges output')
            xlabel(app.axCannyOut, 'X')
            ylabel(app.axCannyOut, 'Y')
            zlabel(app.axCannyOut, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Downscalestep1DropDownLabel = uilabel(app.GridLayout6);
            app.Downscalestep1DropDownLabel.Text = 'Downscale step 1';
            app.ddDownscale1 = uidropdown(app.GridLayout6);
            app.ddDownscale1.Items = {'1x', '2x', '4x', '8x'};
            app.ddDownscale1.Value = '4x';
            app.DilationpxSpinnerLabel = uilabel(app.GridLayout6);
            app.DilationpxSpinnerLabel.Text = 'Dilation, px';
            app.sDilation = uispinner(app.GridLayout6);
            app.sDilation.Limits = [1 16];
            app.sDilation.Value = 2;
            app.Downscalestep2DropDownLabel = uilabel(app.GridLayout6);
            app.Downscalestep2DropDownLabel.Text = 'Downscale step 2';
            app.ddDownscale2 = uidropdown(app.GridLayout6);
            app.ddDownscale2.Items = {'1x', '2x', '4x', '8x'};
            app.ddDownscale2.Value = '4x';
            app.GridLayout6_2 = uigridlayout(app.GridLayout5);
            app.LowthresholdSpinnerLabel = uilabel(app.GridLayout6_2);
            app.LowthresholdSpinnerLabel.Text = 'Low threshold';
            app.sLowThreshold = uispinner(app.GridLayout6_2);
            app.sLowThreshold.Step = 0.05;
            app.sLowThreshold.Limits = [0 95];
            app.sLowThreshold.Value = 0.05;
            app.HighthresholdLabel = uilabel(app.GridLayout6_2);
            app.HighthresholdLabel.Text = 'High threshold';
            app.sHighThreshold = uispinner(app.GridLayout6_2);
            app.sHighThreshold.Step = 0.05;
            app.sHighThreshold.Limits = [0 95];
            app.sHighThreshold.Value = 0.3;
            app.SigmaSpinnerLabel = uilabel(app.GridLayout6_2);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.sSigma = uispinner(app.GridLayout6_2);
            app.sSigma.Step = 0.2;
            app.sSigma.Limits = [1 10];
            app.sSigma.Value = 5;
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.bSaveSettings = uibutton(app.GridLayout7, 'push');
            app.bSaveSettings.Text = 'Save settings';
            app.bLoadSettings = uibutton(app.GridLayout7, 'push');
            app.bLoadSettings.Text = 'Load settings';
            app.OutputPanel = uipanel(app.GridLayout4);
            app.OutputPanel.Title = 'Output';
            app.GridLayout8 = uigridlayout(app.OutputPanel);
            app.chkCreateSubdir = uicheckbox(app.GridLayout8);
            app.chkCreateSubdir.Text = 'Create a subdirectory';
            app.chkCreateSubdir.Value = true;
            app.bDemuxTraces = uibutton(app.GridLayout8, 'push');
            app.bDemuxTraces.Text = 'Demux traces';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = microarrayDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end